classdef Timnghiem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GiithiunhmTab                   matlab.ui.container.Tab
        Label_12                        matlab.ui.control.Label
        ThitkgiaodinvlunlphnHiquyLabel_2  matlab.ui.control.Label
        NguynTnKitLabel                 matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        ThitkgiaodinvlunlphnTmnghimLabel  matlab.ui.control.Label
        NguynHongPhcLabel               matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        ThitkgiaodinvlunlphnTchphnLabel  matlab.ui.control.Label
        NguynTnPhongLabel               matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        ThitkgiaodinvlunlphnHiquyLabel  matlab.ui.control.Label
        BchMinhPhcLabel                 matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        VitbocowordthitkgiaodingiithiuLabel  matlab.ui.control.Label
        VHongNamLabel                   matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ThitkgiaodinvlunlphnohmLabel    matlab.ui.control.Label
        NguynMnhNhtMinhLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TINHONTHNHLabel                 matlab.ui.control.Label
        NHIMVLabel                      matlab.ui.control.Label
        HVTNLabel                       matlab.ui.control.Label
        MSSVLabel                       matlab.ui.control.Label
        THNHVINNHM8Label                matlab.ui.control.Label
        TmnghimTab                      matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        solanlapNmrField                matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel             matlab.ui.control.Label
        nghiemNmrField                  matlab.ui.control.NumericEditField
        NghimLabel                      matlab.ui.control.Label
        resultBtn                       matlab.ui.control.Button
        choose                          matlab.ui.container.ButtonGroup
        newtonBtn                       matlab.ui.control.RadioButton
        lapBtn                          matlab.ui.control.RadioButton
        chiadoiBtn                      matlab.ui.control.RadioButton
        saisoNmrField                   matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel        matlab.ui.control.Label
        khoangphai                      matlab.ui.control.NumericEditField
        KhongphiLabel                   matlab.ui.control.Label
        khoangtrai                      matlab.ui.control.NumericEditField
        KhongtriLabel                   matlab.ui.control.Label
        showres_3                       matlab.ui.control.Label
        Luuy                            matlab.ui.control.Label
        phuongtrinhTxtField             matlab.ui.control.EditField
        PhngtrnhLabel                   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        NisuyTab                        matlab.ui.container.Tab
        dathucnoisuyEditField           matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel     matlab.ui.control.Label
        KetquaButton                    matlab.ui.control.Button
        xEditField                      matlab.ui.control.EditField
        xEditFieldLabel                 matlab.ui.control.Label
        KetquanoisuyEditField           matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel         matlab.ui.control.Label
        GiatrinoisuyEditField           matlab.ui.control.NumericEditField
        GitrcnnisuyEditFieldLabel       matlab.ui.control.Label
        PhuongphapButtonGroup           matlab.ui.container.ButtonGroup
        LagrangeButton                  matlab.ui.control.RadioButton
        NewtonButton                    matlab.ui.control.RadioButton
        yEditField                      matlab.ui.control.EditField
        yEditFieldLabel                 matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        HiquyTab                        matlab.ui.container.Tab
        KetquahoiquyButton              matlab.ui.control.Button
        KetquadudoanEditField           matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel           matlab.ui.control.Label
        GiatridudoanEditField           matlab.ui.control.NumericEditField
        GitrdonEditFieldLabel           matlab.ui.control.Label
        phuongtrinhhoiquyEditField      matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        phuongphapquyButtonGroup        matlab.ui.container.ButtonGroup
        LogaritButton                   matlab.ui.control.RadioButton
        HammuButton                     matlab.ui.control.RadioButton
        TuyentinhButton                 matlab.ui.control.RadioButton
        yhoiquyEditField                matlab.ui.control.EditField
        yEditField_2Label               matlab.ui.control.Label
        xhoiquyEditField                matlab.ui.control.EditField
        xEditField_2Label               matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        ohmTab                          matlab.ui.container.Tab
        ohmtheoxyLabel                  matlab.ui.control.Label
        ketquadaohamtheohamsovahEditField  matlab.ui.control.NumericEditField
        ohmtheohmsvhEditFieldLabel      matlab.ui.control.Label
        ketquadaohamtheoxvayEditField   matlab.ui.control.NumericEditField
        KtquButton                      matlab.ui.control.Button
        HocLabel                        matlab.ui.control.Label
        giatricantinhdaohamEditField    matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel      matlab.ui.control.Label
        PhngphpohmButtonGroup           matlab.ui.container.ButtonGroup
        TrungtamButton                  matlab.ui.control.RadioButton
        LuiButton                       matlab.ui.control.RadioButton
        TienButton                      matlab.ui.control.RadioButton
        GitrsaisButtonGroup             matlab.ui.container.ButtonGroup
        Oh2Button                       matlab.ui.control.RadioButton
        OhButton                        matlab.ui.control.RadioButton
        BuochEditField                  matlab.ui.control.NumericEditField
        BchEditFieldLabel               matlab.ui.control.Label
        HamsoEditField                  matlab.ui.control.EditField
        HmsEditFieldLabel               matlab.ui.control.Label
        ydaohamEditField                matlab.ui.control.EditField
        yEditField_2Label_2             matlab.ui.control.Label
        xdaohamEditField                matlab.ui.control.EditField
        xEditField_2Label_2             matlab.ui.control.Label
        TchphnTab                       matlab.ui.container.Tab
        NwarningLabel                   matlab.ui.control.Label
        CanduoiEditField                matlab.ui.control.NumericEditField
        CndiLabel                       matlab.ui.control.Label
        KetquatichphanEditField         matlab.ui.control.NumericEditField
        KetquatichphanButton            matlab.ui.control.Button
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        CantrenEditField                matlab.ui.control.NumericEditField
        CntrnLabel                      matlab.ui.control.Label
        PhngphptchphnButtonGroup        matlab.ui.container.ButtonGroup
        Simpson38Button                 matlab.ui.control.RadioButton
        Simpson13Button                 matlab.ui.control.RadioButton
        HinhthangButton                 matlab.ui.control.RadioButton
        HamsotichphanEditField          matlab.ui.control.EditField
        HmsEditFieldLabel_2             matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: resultBtn
        function resultBtnPushed(app, event)
            ham = app.phuongtrinhTxtField.Value;
            above = app.khoangtrai.Value;
            under = app.khoangphai.Value;
            saiso = app.saisoNmrField.Value;
            if(app.chiadoiBtn.Value == 1)
                x = above:0.2:under;
                [app.nghiemNmrField.Value,app.solanlapNmrField.Value] = chiadoi(ham,above,under,saiso,x,app);
            elseif(app.lapBtn.Value == 1)
                x = above:0.2:under;
                [app.nghiemNmrField.Value,app.solanlapNmrField.Value] = lap(ham,above,under,saiso,x,app);
            else
                x = above:0.2:under;
                [app.nghiemNmrField.Value,app.solanlapNmrField.Value] = tieptuyen(ham,above,under,saiso,x,app);
            end
        end

        % Selection changed function: choose
        function chooseSelectionChanged(app, event)
            selectedButton = app.choose.SelectedObject;
            if(app.chiadoiBtn.Value == 1) || (app.newtonBtn.Value == 1)
                app.phuongtrinhTxtField.Value = 'x-sin(x)-0.25';
            else app.phuongtrinhTxtField.Value = 'sin(x)+0.25';
            end
        end

        % Button pushed function: KetquaButton
        function KetquaButtonPushed(app, event)
            x = str2num(app.xEditField.Value);
            y = str2num(app.yEditField.Value);
            if(app.LagrangeButton.Value == 1)
                app.KetquanoisuyEditField.Value = Lagrange(x,y,app.GiatrinoisuyEditField.Value);
                app.dathucnoisuyEditField.Value = symsLagrange(x,y,app);
            else
                app.KetquanoisuyEditField.Value = NewtonInterpolation(x,y,app.GiatrinoisuyEditField.Value);
                app.dathucnoisuyEditField.Value = symsNewton(x,y,app);
            end
        end

        % Button pushed function: KetquahoiquyButton
        function KetquahoiquyButtonPushed(app, event)
            x = str2num(app.xhoiquyEditField.Value);
            y = str2num(app.yhoiquyEditField.Value);
            pre = app.GiatridudoanEditField.Value;
            if(app.TuyentinhButton.Value == 1)
                [app.phuongtrinhhoiquyEditField.Value,app.KetquadudoanEditField.Value] = Hoiquytuyentinh(x,y,pre,app);
                
            elseif(app.HammuButton.Value == 1)
                [app.phuongtrinhhoiquyEditField.Value,app.KetquadudoanEditField.Value] = Hoiquyhammu(x,y,pre,app);
            else
                [app.phuongtrinhhoiquyEditField.Value,app.KetquadudoanEditField.Value] = Hoiquylogarit(x,y,pre,app);
            end
        end

        % Selection changed function: phuongphapquyButtonGroup
        function phuongphapquyButtonGroupSelectionChanged(app, event)
            selectedButton = app.phuongphapquyButtonGroup.SelectedObject;
            if(app.HammuButton.Value == 1) || (app.LogaritButton.Value == 1)
                app.xhoiquyEditField.Value = '[2.5,3.5,5,6,7.5,10,12.5,15,17.5,20]';
                app.yhoiquyEditField.Value = '[13,11,8.5,8.2,7,6.2,5.2,4.8,4.6,4.3]';
                app.GiatridudoanEditField.Value = 9;
            elseif(app.TuyentinhButton.Value == 1)
                app.xhoiquyEditField.Value = '[1,2,3,4,5,6,7,8,9]';
                app.yhoiquyEditField.Value = '[1,1.5,2,3,4,5,8,10,13]';
                app.GiatridudoanEditField.Value = 5;
            end
        end

        % Button pushed function: KtquButton
        function KtquButtonPushed(app, event)
            x = str2num(app.xdaohamEditField.Value);
            y = str2num(app.ydaohamEditField.Value);
            app.ketquadaohamtheoxvayEditField.Value = daoham1(x,y,app.giatricantinhdaohamEditField.Value);
            app.ketquadaohamtheohamsovahEditField.Value = daoham2(app.HamsoEditField.Value,app.giatricantinhdaohamEditField.Value,app.BuochEditField.Value,app);
        end

        % Selection changed function: GitrsaisButtonGroup
        function GitrsaisButtonGroupSelectionChanged(app, event)
            selectedButton = app.GitrsaisButtonGroup.SelectedObject;
            if(app.OhButton.Value == 1)
                app.TrungtamButton.Visible = 0;
            else
                 app.TrungtamButton.Visible = 1;
            end
        end

        % Button pushed function: KetquatichphanButton
        function KetquatichphanButtonPushed(app, event)
            ham = app.HamsotichphanEditField.Value;
            a = app.CantrenEditField.Value;
            b = app.CanduoiEditField.Value;
            n = app.NEditField.Value;
            if(app.HinhthangButton.Value == 1)
                app.KetquatichphanEditField.Value = TichPhanHinhThang(ham,a,b,n);
            elseif(app.Simpson13Button.Value == 1)
                 app.KetquatichphanEditField.Value = tichphansimpson13(ham,a,b,n);
            else
                if(rem(app.NEditField.Value,3) == 0)
                    app.KetquatichphanEditField.Value = tichphansimpson38(ham,a,b,n);
                    app.NwarningLabel.Visible = 0;
                else
                    app.NwarningLabel.Visible = 1;
                end
            end
        end

        % Selection changed function: PhngphptchphnButtonGroup
        function PhngphptchphnButtonGroupSelectionChanged(app, event)
            selectedButton = app.PhngphptchphnButtonGroup.SelectedObject;
            if(app.HinhthangButton.Value == 1) || (app.Simpson13Button.Value == 1)
                 app.NwarningLabel.Visible = 0;
                 app.NEditField.Value = 10;
            else
                app.NEditField.Value = 9;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.THNHVINNHM8Label = uilabel(app.GiithiunhmTab);
            app.THNHVINNHM8Label.Text = 'THÀNH VIÊN NHÓM 8';
            app.MSSVLabel = uilabel(app.GiithiunhmTab);
            app.MSSVLabel.Text = 'MSSV';
            app.HVTNLabel = uilabel(app.GiithiunhmTab);
            app.HVTNLabel.Text = 'HỌ VÀ TÊN';
            app.NHIMVLabel = uilabel(app.GiithiunhmTab);
            app.NHIMVLabel.Text = 'NHIỆM VỤ';
            app.TINHONTHNHLabel = uilabel(app.GiithiunhmTab);
            app.TINHONTHNHLabel.Text = 'TIẾN ĐỘ HOÀN THÀNH';
            app.Label = uilabel(app.GiithiunhmTab);
            app.Label.Text = '21207181';
            app.NguynMnhNhtMinhLabel = uilabel(app.GiithiunhmTab);
            app.NguynMnhNhtMinhLabel.Text = 'Nguyễn Mạnh Nhật Minh';
            app.ThitkgiaodinvlunlphnohmLabel = uilabel(app.GiithiunhmTab);
            app.ThitkgiaodinvlunlphnohmLabel.Text = 'Thiết kế giao diện và luận lý phần Đạo hàm';
            app.Label_2 = uilabel(app.GiithiunhmTab);
            app.Label_2.Text = '100%';
            app.Label_3 = uilabel(app.GiithiunhmTab);
            app.Label_3.Text = '21207182';
            app.VHongNamLabel = uilabel(app.GiithiunhmTab);
            app.VHongNamLabel.Text = 'Võ Hoàng Nam';
            app.VitbocowordthitkgiaodingiithiuLabel = uilabel(app.GiithiunhmTab);
            app.VitbocowordthitkgiaodingiithiuLabel.Text = 'Viết báo cáo word, thiết kế giao diện giới thiệu';
            app.Label_4 = uilabel(app.GiithiunhmTab);
            app.Label_4.Text = '100%';
            app.Label_5 = uilabel(app.GiithiunhmTab);
            app.Label_5.Text = '21207194';
            app.BchMinhPhcLabel = uilabel(app.GiithiunhmTab);
            app.BchMinhPhcLabel.Text = 'Bạch Minh Phúc';
            app.ThitkgiaodinvlunlphnHiquyLabel = uilabel(app.GiithiunhmTab);
            app.ThitkgiaodinvlunlphnHiquyLabel.Text = 'Thiết kế giao diện và luận lý phần Hồi quy';
            app.Label_6 = uilabel(app.GiithiunhmTab);
            app.Label_6.Text = '100%';
            app.Label_7 = uilabel(app.GiithiunhmTab);
            app.Label_7.Text = '21207192';
            app.NguynTnPhongLabel = uilabel(app.GiithiunhmTab);
            app.NguynTnPhongLabel.Text = 'Nguyễn Tấn Phong';
            app.ThitkgiaodinvlunlphnTchphnLabel = uilabel(app.GiithiunhmTab);
            app.ThitkgiaodinvlunlphnTchphnLabel.Text = 'Thiết kế giao diện và luận lý phần Tích phân';
            app.Label_8 = uilabel(app.GiithiunhmTab);
            app.Label_8.Text = '100%';
            app.Label_9 = uilabel(app.GiithiunhmTab);
            app.Label_9.Text = '21207196';
            app.NguynHongPhcLabel = uilabel(app.GiithiunhmTab);
            app.NguynHongPhcLabel.Text = 'Nguyễn Hoàng Phúc';
            app.ThitkgiaodinvlunlphnTmnghimLabel = uilabel(app.GiithiunhmTab);
            app.ThitkgiaodinvlunlphnTmnghimLabel.Text = 'Thiết kế giao diện và luận lý phần Tìm nghiệm';
            app.Label_10 = uilabel(app.GiithiunhmTab);
            app.Label_10.Text = '100%';
            app.Label_11 = uilabel(app.GiithiunhmTab);
            app.Label_11.Text = '21207174';
            app.NguynTnKitLabel = uilabel(app.GiithiunhmTab);
            app.NguynTnKitLabel.Text = 'Nguyễn Tấn Kiệt';
            app.ThitkgiaodinvlunlphnHiquyLabel_2 = uilabel(app.GiithiunhmTab);
            app.ThitkgiaodinvlunlphnHiquyLabel_2.Text = 'Thiết kế giao diện và luận lý phần Hồi quy';
            app.Label_12 = uilabel(app.GiithiunhmTab);
            app.Label_12.Text = '100%';
            app.TmnghimTab = uitab(app.TabGroup);
            app.TmnghimTab.Title = 'Tìm nghiệm';
            app.Panel = uipanel(app.TmnghimTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PhngtrnhLabel = uilabel(app.Panel);
            app.PhngtrnhLabel.Text = 'Phương trình';
            app.phuongtrinhTxtField = uieditfield(app.Panel, 'text');
            app.phuongtrinhTxtField.Value = 'x-sin(x)-0.25';
            app.Luuy = uilabel(app.Panel);
            app.Luuy.Text = 'Lưu ý: Nếu sử dụng phương pháp lặp, tìm hàm g(x) = x sao cho thỏa điều kiện |g''(x)| <= q < 1';
            app.showres_3 = uilabel(app.Panel);
            app.showres_3.Text = 'Khoảng phân ly nghiệm:';
            app.KhongtriLabel = uilabel(app.Panel);
            app.KhongtriLabel.Text = 'Khoảng trái';
            app.khoangtrai = uieditfield(app.Panel, 'numeric');
            app.khoangtrai.Value = -1;
            app.KhongphiLabel = uilabel(app.Panel);
            app.KhongphiLabel.Text = 'Khoảng phải';
            app.khoangphai = uieditfield(app.Panel, 'numeric');
            app.khoangphai.Value = 2;
            app.SaischophpEditFieldLabel = uilabel(app.Panel);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.saisoNmrField = uieditfield(app.Panel, 'numeric');
            app.saisoNmrField.Value = 0.005;
            app.choose = uibuttongroup(app.Panel);
            app.choose.SelectionChangedFcn = createCallbackFcn(app, @chooseSelectionChanged, true);
            app.choose.Title = 'Phương pháp';
            app.chiadoiBtn = uiradiobutton(app.choose);
            app.chiadoiBtn.Text = 'Chia đôi';
            app.chiadoiBtn.Value = true;
            app.lapBtn = uiradiobutton(app.choose);
            app.lapBtn.Text = 'Lặp';
            app.newtonBtn = uiradiobutton(app.choose);
            app.newtonBtn.Text = 'Newton(Tiếp tuyến)';
            app.resultBtn = uibutton(app.Panel, 'push');
            app.resultBtn.ButtonPushedFcn = createCallbackFcn(app, @resultBtnPushed, true);
            app.resultBtn.Text = 'Kết quả';
            app.NghimLabel = uilabel(app.Panel);
            app.NghimLabel.Text = 'Nghiệm:';
            app.nghiemNmrField = uieditfield(app.Panel, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.Panel);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.solanlapNmrField = uieditfield(app.Panel, 'numeric');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NisuyTab);
            title(app.UIAxes2, 'Đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.yEditFieldLabel = uilabel(app.NisuyTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.NisuyTab, 'text');
            app.yEditField.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.PhuongphapButtonGroup = uibuttongroup(app.NisuyTab);
            app.PhuongphapButtonGroup.Title = 'Phương pháp nội suy';
            app.NewtonButton = uiradiobutton(app.PhuongphapButtonGroup);
            app.NewtonButton.Text = 'Newton';
            app.NewtonButton.Value = true;
            app.LagrangeButton = uiradiobutton(app.PhuongphapButtonGroup);
            app.LagrangeButton.Text = 'Lagrange';
            app.GitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.GitrcnnisuyEditFieldLabel.Text = 'Giá trị cần nội suy';
            app.GiatrinoisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.GiatrinoisuyEditField.Value = 0.14;
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KetquanoisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.xEditFieldLabel = uilabel(app.NisuyTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.NisuyTab, 'text');
            app.xEditField.Value = '[0.1,0.2,0.3,0.4]';
            app.KetquaButton = uibutton(app.NisuyTab, 'push');
            app.KetquaButton.ButtonPushedFcn = createCallbackFcn(app, @KetquaButtonPushed, true);
            app.KetquaButton.Text = 'Kết quả';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.dathucnoisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes3 = uiaxes(app.HiquyTab);
            title(app.UIAxes3, 'Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.xEditField_2Label = uilabel(app.HiquyTab);
            app.xEditField_2Label.Text = 'x';
            app.xhoiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.xhoiquyEditField.Value = '[1,2,3,4,5,6,7,8,9]';
            app.yEditField_2Label = uilabel(app.HiquyTab);
            app.yEditField_2Label.Text = 'y';
            app.yhoiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.yhoiquyEditField.Value = '[1,1.5,2,3,4,5,8,10,13]';
            app.phuongphapquyButtonGroup = uibuttongroup(app.HiquyTab);
            app.phuongphapquyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @phuongphapquyButtonGroupSelectionChanged, true);
            app.phuongphapquyButtonGroup.Title = 'Phương pháp hồi quy';
            app.TuyentinhButton = uiradiobutton(app.phuongphapquyButtonGroup);
            app.TuyentinhButton.Text = 'Tuyến tính';
            app.TuyentinhButton.Value = true;
            app.HammuButton = uiradiobutton(app.phuongphapquyButtonGroup);
            app.HammuButton.Text = 'Hàm mũ';
            app.LogaritButton = uiradiobutton(app.phuongphapquyButtonGroup);
            app.LogaritButton.Text = 'Logarit';
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.phuongtrinhhoiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.GitrdonEditFieldLabel = uilabel(app.HiquyTab);
            app.GitrdonEditFieldLabel.Text = 'Giá trị dự đoán';
            app.GiatridudoanEditField = uieditfield(app.HiquyTab, 'numeric');
            app.GiatridudoanEditField.Value = 5;
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KetquadudoanEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KetquahoiquyButton = uibutton(app.HiquyTab, 'push');
            app.KetquahoiquyButton.ButtonPushedFcn = createCallbackFcn(app, @KetquahoiquyButtonPushed, true);
            app.KetquahoiquyButton.Text = 'Kết quả';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.xEditField_2Label_2 = uilabel(app.ohmTab);
            app.xEditField_2Label_2.Text = 'x';
            app.xdaohamEditField = uieditfield(app.ohmTab, 'text');
            app.xdaohamEditField.Value = '[0.1,0.3,0.5,0.7,0.9] ';
            app.yEditField_2Label_2 = uilabel(app.ohmTab);
            app.yEditField_2Label_2.Text = 'y';
            app.ydaohamEditField = uieditfield(app.ohmTab, 'text');
            app.ydaohamEditField.Value = '[0.1002,0.3047,0.5236,0.7754,1.1198]';
            app.HmsEditFieldLabel = uilabel(app.ohmTab);
            app.HmsEditFieldLabel.Text = 'Hàm số';
            app.HamsoEditField = uieditfield(app.ohmTab, 'text');
            app.HamsoEditField.Value = 'asin(x)';
            app.BchEditFieldLabel = uilabel(app.ohmTab);
            app.BchEditFieldLabel.Text = 'Bước h';
            app.BuochEditField = uieditfield(app.ohmTab, 'numeric');
            app.BuochEditField.Value = 0.2;
            app.GitrsaisButtonGroup = uibuttongroup(app.ohmTab);
            app.GitrsaisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GitrsaisButtonGroupSelectionChanged, true);
            app.GitrsaisButtonGroup.Title = 'Giá trị sai số';
            app.OhButton = uiradiobutton(app.GitrsaisButtonGroup);
            app.OhButton.Text = 'O(h)';
            app.OhButton.Value = true;
            app.Oh2Button = uiradiobutton(app.GitrsaisButtonGroup);
            app.Oh2Button.Text = 'O(h^2)';
            app.PhngphpohmButtonGroup = uibuttongroup(app.ohmTab);
            app.PhngphpohmButtonGroup.Title = 'Phương pháp đạo hàm';
            app.TienButton = uiradiobutton(app.PhngphpohmButtonGroup);
            app.TienButton.Text = 'Tiến';
            app.TienButton.Value = true;
            app.LuiButton = uiradiobutton(app.PhngphpohmButtonGroup);
            app.LuiButton.Text = 'Lùi';
            app.TrungtamButton = uiradiobutton(app.PhngphpohmButtonGroup);
            app.TrungtamButton.Visible = 'off';
            app.TrungtamButton.Text = 'Trung tâm';
            app.GitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.giatricantinhdaohamEditField = uieditfield(app.ohmTab, 'numeric');
            app.giatricantinhdaohamEditField.Value = 0.5;
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.KtquButton = uibutton(app.ohmTab, 'push');
            app.KtquButton.ButtonPushedFcn = createCallbackFcn(app, @KtquButtonPushed, true);
            app.KtquButton.Text = 'Kết quả';
            app.ketquadaohamtheoxvayEditField = uieditfield(app.ohmTab, 'numeric');
            app.ohmtheohmsvhEditFieldLabel = uilabel(app.ohmTab);
            app.ohmtheohmsvhEditFieldLabel.Text = 'Đạo hàm theo hàm số và h';
            app.ketquadaohamtheohamsovahEditField = uieditfield(app.ohmTab, 'numeric');
            app.ohmtheoxyLabel = uilabel(app.ohmTab);
            app.ohmtheoxyLabel.Text = 'Đạo hàm theo x, y';
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HmsEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.HmsEditFieldLabel_2.Text = 'Hàm số';
            app.HamsotichphanEditField = uieditfield(app.TchphnTab, 'text');
            app.HamsotichphanEditField.Value = 'x.^3.*sin(x)';
            app.PhngphptchphnButtonGroup = uibuttongroup(app.TchphnTab);
            app.PhngphptchphnButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhngphptchphnButtonGroupSelectionChanged, true);
            app.PhngphptchphnButtonGroup.Title = 'Phương pháp tích phân';
            app.HinhthangButton = uiradiobutton(app.PhngphptchphnButtonGroup);
            app.HinhthangButton.Text = 'Hình thang';
            app.HinhthangButton.Value = true;
            app.Simpson13Button = uiradiobutton(app.PhngphptchphnButtonGroup);
            app.Simpson13Button.Text = 'Simpson 1/3';
            app.Simpson38Button = uiradiobutton(app.PhngphptchphnButtonGroup);
            app.Simpson38Button.Text = 'Simpson 3/8';
            app.CntrnLabel = uilabel(app.TchphnTab);
            app.CntrnLabel.Text = 'Cận trên';
            app.CantrenEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NEditFieldLabel = uilabel(app.TchphnTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NEditField.Value = 10;
            app.KetquatichphanButton = uibutton(app.TchphnTab, 'push');
            app.KetquatichphanButton.ButtonPushedFcn = createCallbackFcn(app, @KetquatichphanButtonPushed, true);
            app.KetquatichphanButton.Text = 'Kết quả';
            app.KetquatichphanEditField = uieditfield(app.TchphnTab, 'numeric');
            app.CndiLabel = uilabel(app.TchphnTab);
            app.CndiLabel.Text = 'Cận dưới';
            app.CanduoiEditField = uieditfield(app.TchphnTab, 'numeric');
            app.CanduoiEditField.Value = 1;
            app.NwarningLabel = uilabel(app.TchphnTab);
            app.NwarningLabel.Visible = 'off';
            app.NwarningLabel.Text = 'N phải chia hết cho 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Timnghiem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end